classdef simualtion_plotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        StartsimulationButton        matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        SimulationtestDropDownLabel  matlab.ui.control.Label
        SimulationtestDropDown       matlab.ui.control.DropDown
        PlotButton                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Get a list of all files and folders in this folder.
            files = dir('..\simulation_tests\');
            % Get a logical vector that tells which is a directory.
            dirFlags = [files.isdir];
            % Extract only those that are directories.
            subFolders = files(dirFlags);
            % Print folder names to command window.
            test_folders(1) = "";
            i = 1;
            for k = 1 : length(subFolders)
              if startsWith(subFolders(k).name,"test_")
                fprintf('%s\n', subFolders(k).name)
                test_folders(i) = subFolders(k).name;
                i = i + 1;
              end
            end
            app.SimulationtestDropDown.Items = test_folders;
            
        end

        % Button pushed function: StartsimulationButton
        function StartsimulationButtonPushed(app, event)
            start_simulation = "wsl python3 ../simulation_tests/" + string(app.SimulationtestDropDown.Value) + "/start_test.py";
            system(start_simulation)
            cla(app.UIAxes)
            data = readmatrix("../simulation_tests/" + char(app.SimulationtestDropDown.Value) + "/tmp/output_file.txt"); 
            plot(app.UIAxes, data)
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            cla(app.UIAxes)
            data = readmatrix("../simulation_tests/" + char(app.SimulationtestDropDown.Value) + "/tmp/output_file.txt"); 
            plot(app.UIAxes, data)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartsimulationButton = uibutton(app.UIFigure, 'push');
            app.StartsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartsimulationButtonPushed, true);
            app.StartsimulationButton.Text = 'Start simulation';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SimulationtestDropDownLabel = uilabel(app.UIFigure);
            app.SimulationtestDropDownLabel.Text = 'Simulation test:';
            app.SimulationtestDropDown = uidropdown(app.UIFigure);
            app.SimulationtestDropDown.Items = {};
            app.SimulationtestDropDown.Value = {};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simualtion_plotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end