classdef simulation_recorder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        EnginespeedPanel    matlab.ui.container.Panel
        Engine3GaugeLabel   matlab.ui.control.Label
        Engine3Gauge        matlab.ui.control.Gauge
        Engine4GaugeLabel   matlab.ui.control.Label
        Engine4Gauge        matlab.ui.control.Gauge
        Engine2GaugeLabel   matlab.ui.control.Label
        Engine2Gauge        matlab.ui.control.Gauge
        Engine1GaugeLabel   matlab.ui.control.Label
        Engine1Gauge        matlab.ui.control.Gauge
        ControlpanelPanel   matlab.ui.container.Panel
        RecordingSwitch     matlab.ui.control.Switch
        StatusLamp          matlab.ui.control.Lamp
        KnobLabel           matlab.ui.control.Label
        Knob                matlab.ui.control.Knob
        Panel               matlab.ui.container.Panel
        PitchGaugeLabel     matlab.ui.control.Label
        PitchGauge          matlab.ui.control.SemicircularGauge
        RollGaugeLabel      matlab.ui.control.Label
        RollGauge           matlab.ui.control.SemicircularGauge
        AltitudeGaugeLabel  matlab.ui.control.Label
        AltitudeGauge       matlab.ui.control.LinearGauge
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %pt = [0 0 0];
            %dir = [1 1 0 1];
            %quiver3(app.UIAxes, pt(1),pt(2),pt(3), dir(1),dir(2),dir(3));
            
        end

        % Value changed function: RecordingSwitch
        function RecordingSwitchValueChanged(app, event)
            value = app.RecordingSwitch.Value;
            if value == "Stop"
                app.StatusLamp.Color = 'red';   
            else
                app.StatusLamp.Color = 'green';
                cla(app.UIAxes)
                h = animatedline(app.UIAxes);
                addpoints(h,0,0)
                data = readmatrix('data.txt');
                data_length = length(data);
                k = 1;
                while k < data_length && app.RecordingSwitch.Value == "Start"
                    app.Engine1Gauge.Value = data(k);
                    addpoints(h,k, data(k));
                    drawnow
                    if k == data_length-1
                        pause(0.05);
                        data = readmatrix('data.txt'); 
                        if length(data) > data_length
                            data_length = length(data);
                        end
                    end
                    k = k + 1;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EnginespeedPanel = uipanel(app.UIFigure);
            app.EnginespeedPanel.Title = 'Engine speed';
            app.Engine3GaugeLabel = uilabel(app.EnginespeedPanel);
            app.Engine3GaugeLabel.Text = 'Engine 3';
            app.Engine3Gauge = uigauge(app.EnginespeedPanel, 'circular');
            app.Engine3Gauge.MajorTickLabels = {'0', '20', '40', '60', '80', '100%'};
            app.Engine4GaugeLabel = uilabel(app.EnginespeedPanel);
            app.Engine4GaugeLabel.Text = 'Engine 4';
            app.Engine4Gauge = uigauge(app.EnginespeedPanel, 'circular');
            app.Engine4Gauge.MajorTickLabels = {'0', '20', '40', '60', '80', '100%'};
            app.Engine2GaugeLabel = uilabel(app.EnginespeedPanel);
            app.Engine2GaugeLabel.Text = 'Engine 2';
            app.Engine2Gauge = uigauge(app.EnginespeedPanel, 'circular');
            app.Engine2Gauge.MajorTickLabels = {'0', '20', '40', '60', '80', '100%'};
            app.Engine1GaugeLabel = uilabel(app.EnginespeedPanel);
            app.Engine1GaugeLabel.Text = 'Engine 1';
            app.Engine1Gauge = uigauge(app.EnginespeedPanel, 'circular');
            app.Engine1Gauge.MajorTickLabels = {'0', '20', '40', '60', '80', '100%'};
            app.ControlpanelPanel = uipanel(app.UIFigure);
            app.ControlpanelPanel.Title = 'Control panel';
            app.RecordingSwitch = uiswitch(app.ControlpanelPanel, 'slider');
            app.RecordingSwitch.Items = {'Stop', 'Start'};
            app.RecordingSwitch.ValueChangedFcn = createCallbackFcn(app, @RecordingSwitchValueChanged, true);
            app.RecordingSwitch.Value = 'Stop';
            app.StatusLamp = uilamp(app.ControlpanelPanel);
            app.KnobLabel = uilabel(app.ControlpanelPanel);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.ControlpanelPanel, 'continuous');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.PitchGaugeLabel = uilabel(app.Panel);
            app.PitchGaugeLabel.Text = 'Pitch';
            app.PitchGauge = uigauge(app.Panel, 'semicircular');
            app.PitchGauge.Limits = [-20 20];
            app.PitchGauge.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.PitchGauge.MinorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.RollGaugeLabel = uilabel(app.Panel);
            app.RollGaugeLabel.Text = 'Roll';
            app.RollGauge = uigauge(app.Panel, 'semicircular');
            app.RollGauge.Limits = [-20 20];
            app.RollGauge.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.RollGauge.MinorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.AltitudeGaugeLabel = uilabel(app.Panel);
            app.AltitudeGaugeLabel.Text = 'Altitude';
            app.AltitudeGauge = uigauge(app.Panel, 'linear');
            app.AltitudeGauge.Limits = [0 5];
            app.AltitudeGauge.MajorTickLabels = {'0 m', '1 m', '2 m', '3 m', '4 m', '5 m'};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulation_recorder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end